classdef SignalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image2_2                       matlab.ui.control.Image
        GENREEditField                 matlab.ui.control.EditField
        GENRELabel_2                   matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        MusicGenreClassificationLabel  matlab.ui.control.Label
        MachineLearningMethodLabel     matlab.ui.control.Label
        MachineLearningMethodDropDown  matlab.ui.control.DropDown
        FileNameField                  matlab.ui.control.EditField
        FileNameLabel                  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        SpectrogramAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
        fullPath %selected audio file
        % Models
        modelFineTree
        modelMediumTree
        modelCoarseTree
        modelLinearSVM
        modelQuadraticSVM
        modelFineKNN
        modelMediumKNN
        modelCoarseKNN
        modelBoostedTrees
        modelNarrowNeuralNetwork
        modelMediumNeuralNetwork
    end
    
    methods (Access = private)
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


  %load models         
load('FineTreeModel.mat', 'trainedModel');
app.modelFineTree = trainedModel;
load('MediumTreeModel.mat', 'trainedModel1');
app.modelMediumTree = trainedModel1;
load('CoarseTreeModel.mat', 'trainedModel2');
app.modelCoarseTree = trainedModel2;
load('LinearSVMModel.mat', 'trainedModel3');
app.modelLinearSVM = trainedModel3;
load('QuadraticSVMModel.mat', 'trainedModel4');
app.modelQuadraticSVM = trainedModel4;
load('FineKNNModel.mat', 'trainedModel5');
app.modelFineKNN = trainedModel5;
load('MediumKNNModel.mat', 'trainedModel6');
app.modelMediumKNN = trainedModel6;
load('CoarseKNNModel.mat', 'trainedModel7');
app.modelCoarseKNN = trainedModel7;
load('BoostedTreesModel.mat', 'trainedModel8');
app.modelBoostedTrees = trainedModel8;
load('NarrowNeuralNetworkModel.mat', 'trainedModel9');
app.modelNarrowNeuralNetwork = trainedModel9;
load('MediumNeuralNetworkModel.mat', 'trainedModel10');
app.modelMediumNeuralNetwork = trainedModel10;
      
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
% Audio file selection (*.mav)
[fileName, pathName] = uigetfile('*.wav', 'Select an audio file');
if isequal(fileName, 0)
    uialert(app.UIFigure, 'The audio file was not selected!', 'Warning!');
    return;
end

% Merge the full file path
app.fullPath = fullfile(pathName, fileName);

% Shred the file name and extension
[~, fileNameWithoutExt, ext] = fileparts(fileName);

% Check if the extension is correct
if ~strcmpi(ext, '.wav')
    % Incorrect file extension message box
    ErrorBox = msgbox("Invalid File Type! (Only '.wav' extension files)", "error");

    % So that the main window is not minimized
    figure(app.UIFigure);
    figure(ErrorBox);
    return; % Stop running the continuation of the code
else
% Showing the file name (without extension)
    app.FileNameField.Value = fileNameWithoutExt;
end

% Upload the audio file
try
    [audioData, fs] = audioread(app.fullPath); % Upload audio data and sampling frequency
catch ME
    uialert(app.UIFigure, 'The audio file could not be uploaded!', 'Warning!');
    disp(ME.message);
    return;
end

% Spectrogram processing
try
    [S, F, T] = spectrogram(audioData, kaiser(2048, 128), 120, 1e3, fs); % Spectrogram calculating
    powerSpectrum = abs(S); % Calculate the power spectrum
    
    % Spectrogram Visualization 
    cla(app.SpectrogramAxes);
    imagesc(app.SpectrogramAxes, T, F, 10 * log10(powerSpectrum));
    axis(app.SpectrogramAxes, 'xy');
    colormap(app.SpectrogramAxes, 'jet');
    colorbar(app.SpectrogramAxes);
    title(app.SpectrogramAxes, 'Spectrogram');
    xlabel(app.SpectrogramAxes, 'Time (s)');
    ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
catch ME
    uialert(app.UIFigure, 'Spectrogram could not be created!', 'Warning!');
    disp(ME.message);
    return;
end

% Creating a Feature Vector
try
    % Removing features from spectrogram and filling them with zero
    flatSpec = abs(S(:)); % Convert a spectrogram to a one-dimensional vector
    requiredLength = 3087; % The required length
    if length(flatSpec) >= requiredLength
        audioTable = flatSpec(1:requiredLength); % Get the first 3087 elements
    else
        audioTable = [flatSpec; zeros(requiredLength - length(flatSpec), 1)]; % Fill in the missing parts with reset
    end
catch ME
    uialert(app.UIFigure, 'The feature vector could not be created!', 'Warning!');
    disp(ME.message);
    return;
end

             % Getting the selected model
modelStr = app.MachineLearningMethodDropDown.Value;

% Selects the right model
switch modelStr
    case "Fine Tree (36.6)"
        model = app.modelFineTree;
    case "Medium Tree (38.8)"
        model = app.modelMediumTree;
    case "Coarse Tree (40.2)"
        model = app.modelCoarseTree;
    case "Linear SVM (58.0)"
        model = app.modelLinearSVM;
    case "Quadratic SVM *(59.8)*"
        model = app.modelQuadraticSVM;
    case "Fine KNN (38.1)"
        model = app.modelFineKNN;
    case "Medium KNN (36.3)"
        model = app.modelMediumKNN;
    case "Coarse KNN (25.1)"
        model = app.modelCoarseKNN;
    case "Boosted Trees (49.9)"
        model = app.modelBoostedTrees;
    case "Narrow Neural Network (53.5)"
        model = app.modelNarrowNeuralNetwork;
    case "Medium Neural Network (50.1)"
        model = app.modelMediumNeuralNetwork;
    otherwise
        uialert(app.UIFigure, 'A valid model has not been selected!', 'Warning!');
        return;
end


try
    % Make a model prediction
    pred = model.predictFcn(audioTable);

    % Classification of the prediction result
    switch pred
        case 1
            app.GENREEditField.Value = 'Blues';
        case 2
            app.GENREEditField.Value = 'Classical';
        case 3
            app.GENREEditField.Value = 'Country';
        case 4
            app.GENREEditField.Value = 'Disco';
        case 5
            app.GENREEditField.Value = 'Hiphop';
        case 6
            app.GENREEditField.Value = 'Jazz';
        case 7
            app.GENREEditField.Value = 'Metal';
        case 8
            app.GENREEditField.Value = 'Pop';
        case 9
            app.GENREEditField.Value = 'Reggae';
        case 10
            app.GENREEditField.Value = 'Rock';
        otherwise
            app.GENREEditField.Value = 'It was unpredictable!';
    end
catch ME
    % If an error occurs during the forecast, show a warning
    uialert(app.UIFigure, 'An error occurred during the forecast!', 'Warning!');
    disp(ME.message);
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpectrogramAxes = uiaxes(app.UIFigure);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'submit.png');
            app.Button.Text = '';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameField = uieditfield(app.UIFigure, 'text');
            app.MachineLearningMethodDropDown = uidropdown(app.UIFigure);
            app.MachineLearningMethodDropDown.Items = {'Fine Tree (36.6)', 'Medium Tree (38.8)', 'Coarse Tree (40.2)', 'Linear SVM (58.0)', 'Quadratic SVM *(59.8)*', 'Fine KNN (38.1)', 'Medium KNN (36.3)', 'Coarse KNN (25.1)', 'Boosted Trees (49.9)', 'Narrow Neural Network (53.5)', 'Medium Neural Network (50.1)'};
            app.MachineLearningMethodDropDown.Value = 'Quadratic SVM *(59.8)*';
            app.MachineLearningMethodLabel = uilabel(app.UIFigure);
            app.MachineLearningMethodLabel.Text = 'Machine Learning Method:';
            app.MusicGenreClassificationLabel = uilabel(app.UIFigure);
            app.MusicGenreClassificationLabel.Interpreter = 'html';
            app.MusicGenreClassificationLabel.Text = 'Music Genre Classification';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'musical-notes.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'predictive-models.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'saxophone.png');
            app.GENRELabel_2 = uilabel(app.UIFigure);
            app.GENRELabel_2.Text = 'GENRE:';
            app.GENREEditField = uieditfield(app.UIFigure, 'text');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'musical-notes.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end